classdef home_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        SVMTab                         matlab.ui.container.Tab
        predictorModal                 matlab.ui.container.Panel
        PredictHeartDiseasePanel       matlab.ui.container.Panel
        SVMLabel_2                     matlab.ui.control.Label
        HeartDiseaseGauge              matlab.ui.control.LinearGauge
        HeartDiseaseGaugeLabel         matlab.ui.control.Label
        CholestrolEditField            matlab.ui.control.NumericEditField
        CholestrolEditFieldLabel       matlab.ui.control.Label
        ThalachEditField               matlab.ui.control.NumericEditField
        ThalachEditFieldLabel          matlab.ui.control.Label
        AgeEditField                   matlab.ui.control.NumericEditField
        AgeEditFieldLabel              matlab.ui.control.Label
        AccuracyEditField_3            matlab.ui.control.NumericEditField
        AccuracyEditField_3Label       matlab.ui.control.Label
        Panel_7                        matlab.ui.container.Panel
        ConfusionMatrixButton_3        matlab.ui.control.Button
        RunSVMButton                   matlab.ui.control.Button
        SVMLabel                       matlab.ui.control.Label
        UIAxes_3                       matlab.ui.control.UIAxes
        KMeansTab                      matlab.ui.container.Tab
        Panel_4                        matlab.ui.container.Panel
        AccuracyEditField_2            matlab.ui.control.NumericEditField
        AccuracyEditField_2Label       matlab.ui.control.Label
        Panel_5                        matlab.ui.container.Panel
        ConfusionMatrixButton_2        matlab.ui.control.Button
        RunKmeansButton                matlab.ui.control.Button
        HomeButton_2                   matlab.ui.control.Button
        KMeansLabel                    matlab.ui.control.Label
        UIAxes_2                       matlab.ui.control.UIAxes
        RandomForestTab                matlab.ui.container.Tab
        Panel_2                        matlab.ui.container.Panel
        ConfusionMatrixRF              matlab.ui.container.Panel
        AccuracyEditField              matlab.ui.control.NumericEditField
        AccuracyEditFieldLabel         matlab.ui.control.Label
        ConfusionMatrixButton          matlab.ui.control.Button
        HomeButton                     matlab.ui.control.Button
        RandomForestLabel              matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        HomeTab                        matlab.ui.container.Tab
        Panel                          matlab.ui.container.Panel
        CitationsTextArea              matlab.ui.control.TextArea
        CitationsTextAreaLabel         matlab.ui.control.Label
        ConclusionsandInferencesTextArea  matlab.ui.control.TextArea
        ConclusionsandInferencesTextAreaLabel  matlab.ui.control.Label
        SupportVectorMachineSVMButton  matlab.ui.control.Button
        RandomForestRFButton           matlab.ui.control.Button
        KMeansClusteringButton         matlab.ui.control.Button
        HeartDiseaseLabel              matlab.ui.control.Label
    end

    
    properties (Access = private)
        SVMModel %stores the SVM model 
        RFModel %stores the RF model 
        KMeansModel %stores the K means model 
        X_train
        y_train
        X_test
        y_test
        RawData
        CleanData
        C % Description
        data
        idx % Description
        cmRF
    end
    
    methods (Access = private)
        
        function loadData(app)
    % Load the data
            raw_data = importdata('processed.cleveland.data');

            % Convert cell data to string, then split into numerical matrix
            if iscell(raw_data)
                raw_data = cellfun(@(x) str2double(strrep(x, '?', 'NaN')), raw_data, 'UniformOutput', false);
                app.data = cell2mat(raw_data);
            else
                app.data = raw_data;
            end
            % Remove rows with missing values (NaN)
            app.data(any(isnan(app.data), 2), :) = [];

            % Split features and labels
            X = app.data(:, 1:end-1);
            y = app.data(:, end);

            % Convert multi-class labels to binary (0: no disease, 1: disease)
            y(y > 0) = 1;


            % --- Train/Test Split (80% Training, 20% Testing) ---
            cv = cvpartition(size(X, 1), 'HoldOut', 0.2);
            app.X_train = X(training(cv,1), :);
            app.y_train = y(training(cv,1), :);
            app.X_test = X(test(cv,1), :);
            app.y_test = y(test(cv,1), :);

        end
        function loadModels(app)
    % Load SVM model
    tmp = load('mySVMModel.mat', 'SVMModel');
    app.SVMModel = tmp.SVMModel;

    % Load RF model
    tmp = load('myRFModel.mat', 'RFModel');
    app.RFModel = tmp.RFModel;

    % Load K-Means data (idx and c)
    tmp = load('myKMModel.mat', 'idx', 'C');
    app.idx = tmp.idx;
    app.C = tmp.C;
end

        
function initRFPage(app)

            y_pred_rf = str2double(predict(app.RFModel, app.X_test));


            % --- Evaluate Model Performance ---
            accuracy_rf = sum(y_pred_rf == app.y_test) / length(app.y_test);
            app.AccuracyEditField.Value = accuracy_rf*100;
            scatter3(app.UIAxes,app.data(y_pred_rf == 0, 1), app.data(y_pred_rf == 0, 8), app.data(y_pred_rf == 0, 5), 20, 'b', 'filled'); % No Disease
             hold(app.UIAxes,"on")
             scatter3(app.UIAxes,app.data(y_pred_rf == 1, 1), app.data(y_pred_rf == 1, 8), app.data(y_pred_rf == 1, 5), 20, 'r', 'filled'); % Disease
                         % % Labels and title
             xlabel(app.UIAxes,'Age(Years)');
             ylabel(app.UIAxes,'Max Heart Rate (bpm)');
             zlabel(app.UIAxes,'Cholesterol (mg/dL)');
             title(app.UIAxes,'3D Scatter Plot of K-means Clusters');
            grid(app.UIAxes,"on");
             legend(app.UIAxes,'No Disease', 'Disease');
             hold(app.UIAxes,"off")
            app.RunRandomForestButton.Position = [271,105,100,23];
            app.cmRF = confusionchart(app.ConfusionMatrixRF,app.y_test, y_pred_rf);

            app.cmRF.Title = 'Confusion Matrix for Random Forest Classifier';
            app.cmRF.Visible = "off";

end
function displayConfusionMatrixRF(app)
    app.ConfusionMatrixRF.Visible = "on"
end

function removeConfusionMatrixRF(app)
    app.ConfusionMatrixRF.Visible = "off"
end

function displayConfusionMatrixKM(app)
    app.ConfusionMatrixKM.Visible = "on"
end

function removeConfusionMatrixKM(app)
    app.ConfusionMatrixKM.Visible = "off"
end

function displayConfusionMatrixSVM(app)
    app.ConfusionMatrixSVM.Visible = "on"
end

function removeConfusionMatrixSVM(app)
    app.ConfusionMatrixSVM.Visible = "off"
end
    
function RunRFPage(app)


            % 
            % %plotting the pred against age chol and thalach.

            % 

            % Displays Page
            app.UIAxes.Visible ="on";

            app.AccuracyEditField.Visible = "on";
            app.AccuracyEditFieldLabel.Visible = "on";
            app.ConfusionMatrixButton.Visible = "on";
            % 
            % 
            %create RF

            % % Confusion Matrix for RF


    end

    function RunSVMPage(app)
    % 1) Predict using your SVM model
    %    (assuming 'app.SVMModel' stores your trained model)
    y_pred = predict(app.SVMModel, app.X_test);

    % 2) Evaluate Model Performance
    accuracy_svm = sum(y_pred == app.y_test) / length(app.y_test);
    app.AccuracyEditField_3.Value = accuracy_svm * 100;

    % 3) Plot the predictions in 3D
    data = app.CleanData;  % or wherever your data is stored

    % Clear any previous plots
    cla(app.UIAxes_3, 'reset');

    % Plot "No Disease" points
    scatter3(app.UIAxes_3, ...
        data(y_pred == 0, 1), data(y_pred == 0, 8), data(y_pred == 0, 5), ...
        20, 'b', 'filled');  % Age, Max Heart Rate, Cholesterol
    hold(app.UIAxes_3, "on");

    % Plot "Disease" points
    scatter3(app.UIAxes_3, ...
        data(y_pred == 1, 1), data(y_pred == 1, 8), data(y_pred == 1, 5), ...
        20, 'r', 'filled');

    xlabel(app.UIAxes_3, 'Age (Years)');
    ylabel(app.UIAxes_3, 'Max Heart Rate (bpm)');
    zlabel(app.UIAxes_3, 'Cholesterol (mg/dL)');
    title(app.UIAxes_3, '3D Scatter Plot of SVM Predictions');
    legend(app.UIAxes_3, 'No Disease', 'Disease');
    grid(app.UIAxes_3, "on");
    hold(app.UIAxes_3, "off");

    % 4) Make axes and accuracy field visible
    app.UIAxes_3.Visible = "on";
    app.AccuracyEditField_3.Visible = "on";
    % If you have a label for this accuracy field, rename it accordingly:
    % app.AccuracyEditFieldLabel_3.Visible = "on";

    % 5) Adjust the button position if needed
    app.RunSVMButton.Position = [271, 105, 100, 23];

    % 6) Show Confusion Matrix Button (if hidden initially)
    app.ConfusionMatrixButton_3.Visible = "on";

    % 7) Confusion Matrix for SVM
    cm = confusionchart(app.predictorModal, app.y_test, y_pred);
    cm.Title = 'Confusion Matrix for SVM Model';
    cm.Visible = "on";
    end
    function RunKMPage(app)
    %--------------------------------------------------
    % 1) Run K-Means Clustering
    %    (Assumes you've already loaded 'data' & 'y')
    %    For example:
    %    [idx, ~] = kmeans(X, 2);  % or however you're clustering
    %
    %    Then 'idx' is [1,2]. We'll convert it to [0,1].
  
    %--------------------------------------------------
    y_pred_kmeans = app.idx - 1;  % Convert [1,2] to [0,1] for consistency

    %--------------------------------------------------
    % 2) Evaluate K-Means Classification Performance
    %--------------------------------------------------
    accuracy_kmeans = sum(y_pred_kmeans == y) / length(y);
    app.AccuracyEditField_2.Value = accuracy_kmeans * 100;

    %--------------------------------------------------
    % 3) Plot the predictions in 3D
    %--------------------------------------------------
    % Clear any previous plot
    cla(app.UIAxes_2, 'reset');

    % Plot "No Disease" cluster
    scatter3(app.UIAxes_2, ...
        data(y_pred_kmeans == 0, 1), ...
        data(y_pred_kmeans == 0, 8), ...
        data(y_pred_kmeans == 0, 5), ...
        20, 'b', 'filled');
    hold(app.UIAxes_2, "on");

    % Plot "Disease" cluster
    scatter3(app.UIAxes_2, ...
        data(y_pred_kmeans == 1, 1), ...
        data(y_pred_kmeans == 1, 8), ...
        data(y_pred_kmeans == 1, 5), ...
        20, 'r', 'filled');

    xlabel(app.UIAxes_2, 'Age (Years)');
    ylabel(app.UIAxes_2, 'Max Heart Rate (bpm)');
    zlabel(app.UIAxes_2, 'Cholesterol (mg/dL)');
    title(app.UIAxes_2, '3D Scatter Plot of K-Means Clusters');
    legend(app.UIAxes_2, 'No Disease', 'Disease');
    grid(app.UIAxes_2, "on");
    hold(app.UIAxes_2, "off");

    % Make axes visible
    app.UIAxes_2.Visible = "on";

    %--------------------------------------------------
    % 4) Housekeeping / Show UI Elements
    %--------------------------------------------------
    % Move/position the button if needed
    app.RunKmeansButton.Position = [271, 105, 100, 23];

    % Show accuracy field & label (if you have a label)
    app.AccuracyEditField_2.Visible = "on";
    % If there's a label for the accuracy field, rename or uncomment:
    % app.AccuracyEditFieldLabel_2.Visible = "on";

    % Show confusion matrix button
    app.ConfusionMatrixButton_2.Visible = "on";

    %--------------------------------------------------
    % 5) Confusion Matrix
    %--------------------------------------------------
    cm = confusionchart(app.Panel_4, y, y_pred_kmeans);
    cm.Title = 'Confusion Matrix for K-Means Classification';
    cm.Visible = "on";
end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            loadData(app);
            loadModels(app);
            initRFPage(app);
        end

        % Callback function
        function KMeansClusteringButtonPushed(app, event)
            km
            close(app.UIFigure)
        end

        % Callback function
        function RandomForestRFButtonPushed(app, event)
            rf
            close(app.UIFigure)
        end

        % Callback function
        function SupportVectorMachineSVMButtonPushed(app, event)
            svm
            close(app.UIFigure)
        end

        % Callback function
        function LoadDataButtonPushed(app, event)
            data = readtable('processed.cleveland.data','FileType','text','ReadRowNames',false);
        end

        % Callback function
        function testdataplotButtonPushed(app, event)
            %% just a test
            data = readtable('processed.cleveland.data','FileType','text','ReadRowNames',false);
            plot(app.UIAxes,data{:,1},data{:,2})
            title(app.UIAxes,"Proof of concept")
        end

        % Button pushed function: ConfusionMatrixButton_2
        function ConfusionMatrixButton_2Pushed(app, event)
           app.UIAxes_2.Position = [10,173,329,201];
           app.Panel_5.Visible = "On";
        end

        % Button pushed function: ConfusionMatrixButton_3
        function ConfusionMatrixButton_3Pushed(app, event)
           app.UIAxes_3.Position = [10,173,329,201];
           app.Panel_7.Visible = "On";
        end

        % Button pushed function: ConfusionMatrixButton
        function ConfusionMatrixButtonPushed(app, event)
                app.UIAxes.Position = [10,173,329,201];
           app.Panel_3.Visible = "On";       
        end

        % Selection change function: TabGroup
        function tabChanged(app, event)
        selectedTab = event.NewValue;
        
        % Check which tab is selected
        if selectedTab == app.RandomForestTab
            RunRFPage(app);
        elseif selectedTab == app.KMeansTab
            RunKMPage(app);
        elseif selectedTab == app.SVMTab
            RunSVMPage(app);
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @tabChanged, true);
            app.SVMTab = uitab(app.TabGroup);
            app.SVMTab.Title = 'SVM';
            app.predictorModal = uipanel(app.SVMTab);
            app.UIAxes_3 = uiaxes(app.predictorModal);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.Visible = 'off';
            app.SVMLabel = uilabel(app.predictorModal);
            app.SVMLabel.Text = 'SVM';
            app.RunSVMButton = uibutton(app.predictorModal, 'push');
            app.RunSVMButton.Text = 'Run SVM';
            app.ConfusionMatrixButton_3 = uibutton(app.predictorModal, 'push');
            app.ConfusionMatrixButton_3.ButtonPushedFcn = createCallbackFcn(app, @ConfusionMatrixButton_3Pushed, true);
            app.ConfusionMatrixButton_3.Visible = 'off';
            app.ConfusionMatrixButton_3.Text = 'Confusion Matrix';
            app.Panel_7 = uipanel(app.predictorModal);
            app.AccuracyEditField_3Label = uilabel(app.predictorModal);
            app.AccuracyEditField_3Label.Visible = 'off';
            app.AccuracyEditField_3Label.Text = 'Accuracy';
            app.AccuracyEditField_3 = uieditfield(app.predictorModal, 'numeric');
            app.AccuracyEditField_3.Editable = 'off';
            app.AccuracyEditField_3.Visible = 'off';
            app.PredictHeartDiseasePanel = uipanel(app.predictorModal);
            app.PredictHeartDiseasePanel.Title = 'Predict Heart Disease';
            app.AgeEditFieldLabel = uilabel(app.PredictHeartDiseasePanel);
            app.AgeEditFieldLabel.Text = 'Age';
            app.AgeEditField = uieditfield(app.PredictHeartDiseasePanel, 'numeric');
            app.ThalachEditFieldLabel = uilabel(app.PredictHeartDiseasePanel);
            app.ThalachEditFieldLabel.Text = 'Thalach';
            app.ThalachEditField = uieditfield(app.PredictHeartDiseasePanel, 'numeric');
            app.CholestrolEditFieldLabel = uilabel(app.PredictHeartDiseasePanel);
            app.CholestrolEditFieldLabel.Text = 'Cholestrol';
            app.CholestrolEditField = uieditfield(app.PredictHeartDiseasePanel, 'numeric');
            app.HeartDiseaseGaugeLabel = uilabel(app.PredictHeartDiseasePanel);
            app.HeartDiseaseGaugeLabel.Text = 'Heart Disease';
            app.HeartDiseaseGauge = uigauge(app.PredictHeartDiseasePanel, 'linear');
            app.HeartDiseaseGauge.Limits = [0 1];
            app.SVMLabel_2 = uilabel(app.PredictHeartDiseasePanel);
            app.SVMLabel_2.Text = 'SVM';
            app.KMeansTab = uitab(app.TabGroup);
            app.KMeansTab.Title = 'K-Means';
            app.Panel_4 = uipanel(app.KMeansTab);
            app.UIAxes_2 = uiaxes(app.Panel_4);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.Visible = 'off';
            app.KMeansLabel = uilabel(app.Panel_4);
            app.KMeansLabel.Text = 'K-Means';
            app.HomeButton_2 = uibutton(app.Panel_4, 'push');
            app.HomeButton_2.Text = 'Home';
            app.RunKmeansButton = uibutton(app.Panel_4, 'push');
            app.RunKmeansButton.Text = 'Run K-means';
            app.ConfusionMatrixButton_2 = uibutton(app.Panel_4, 'push');
            app.ConfusionMatrixButton_2.ButtonPushedFcn = createCallbackFcn(app, @ConfusionMatrixButton_2Pushed, true);
            app.ConfusionMatrixButton_2.Visible = 'off';
            app.ConfusionMatrixButton_2.Text = 'Confusion Matrix';
            app.Panel_5 = uipanel(app.Panel_4);
            app.AccuracyEditField_2Label = uilabel(app.Panel_4);
            app.AccuracyEditField_2Label.Visible = 'off';
            app.AccuracyEditField_2Label.Text = 'Accuracy';
            app.AccuracyEditField_2 = uieditfield(app.Panel_4, 'numeric');
            app.AccuracyEditField_2.Editable = 'off';
            app.AccuracyEditField_2.Visible = 'off';
            app.RandomForestTab = uitab(app.TabGroup);
            app.RandomForestTab.Title = 'Random Forest';
            app.Panel_2 = uipanel(app.RandomForestTab);
            app.UIAxes = uiaxes(app.Panel_2);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.RandomForestLabel = uilabel(app.Panel_2);
            app.RandomForestLabel.Text = 'Random Forest';
            app.HomeButton = uibutton(app.Panel_2, 'push');
            app.HomeButton.Text = 'Home';
            app.ConfusionMatrixButton = uibutton(app.Panel_2, 'push');
            app.ConfusionMatrixButton.ButtonPushedFcn = createCallbackFcn(app, @ConfusionMatrixButtonPushed, true);
            app.ConfusionMatrixButton.Visible = 'off';
            app.ConfusionMatrixButton.Text = 'Confusion Matrix';
            app.AccuracyEditFieldLabel = uilabel(app.Panel_2);
            app.AccuracyEditFieldLabel.Visible = 'off';
            app.AccuracyEditFieldLabel.Text = 'Accuracy';
            app.AccuracyEditField = uieditfield(app.Panel_2, 'numeric');
            app.AccuracyEditField.Editable = 'off';
            app.AccuracyEditField.Visible = 'off';
            app.ConfusionMatrixRF = uipanel(app.Panel_2);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.Panel = uipanel(app.HomeTab);
            app.HeartDiseaseLabel = uilabel(app.Panel);
            app.HeartDiseaseLabel.Text = 'Heart Disease';
            app.KMeansClusteringButton = uibutton(app.Panel, 'push');
            app.KMeansClusteringButton.Text = 'K-Means Clustering';
            app.RandomForestRFButton = uibutton(app.Panel, 'push');
            app.RandomForestRFButton.Text = 'Random Forest(RF)';
            app.SupportVectorMachineSVMButton = uibutton(app.Panel, 'push');
            app.SupportVectorMachineSVMButton.Text = 'Support Vector Machine(SVM)';
            app.ConclusionsandInferencesTextAreaLabel = uilabel(app.Panel);
            app.ConclusionsandInferencesTextAreaLabel.Text = 'Conclusions and Inferences';
            app.ConclusionsandInferencesTextArea = uitextarea(app.Panel);
            app.ConclusionsandInferencesTextArea.Editable = 'off';
            app.CitationsTextAreaLabel = uilabel(app.Panel);
            app.CitationsTextAreaLabel.Text = 'Citations';
            app.CitationsTextArea = uitextarea(app.Panel);
            app.CitationsTextArea.Editable = 'off';
            app.CitationsTextArea.Value = {'UCI Machine Learning Repository. "Cleveland Heart Disease Data Set." UCI Repository, https://archive.ics.uci.edu/ml/datasets/Heart+Disease.'; 'Detrano, R., et al. "Cleveland Heart Disease Data Set." UCI Machine Learning Repository, 1988. '; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = home_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end