classdef home_app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PredictHeartDiseaseModal       matlab.ui.container.Panel
        CholestrolKnob                 matlab.ui.control.Knob
        CholestrolKnobLabel            matlab.ui.control.Label
        AgeKnob                        matlab.ui.control.Knob
        AgeLabel                       matlab.ui.control.Label
        ThalachKnob                    matlab.ui.control.Knob
        ThalachKnobLabel               matlab.ui.control.Label
        CloseButton                    matlab.ui.control.Button
        LabelModel                     matlab.ui.control.Label
        HeartDiseaseGauge              matlab.ui.control.LinearGauge
        HeartDiseaseGaugeLabel         matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        HomeTab                        matlab.ui.container.Tab
        Panel                          matlab.ui.container.Panel
        CitationsTextArea              matlab.ui.control.TextArea
        CitationsTextAreaLabel         matlab.ui.control.Label
        ConclusionsandInferencesTextArea  matlab.ui.control.TextArea
        ConclusionsandInferencesTextAreaLabel  matlab.ui.control.Label
        SupportVectorMachineSVMButton  matlab.ui.control.Button
        RandomForestRFButton           matlab.ui.control.Button
        KMeansClusteringButton         matlab.ui.control.Button
        HeartDiseaseLabel              matlab.ui.control.Label
        RandomForestTab                matlab.ui.container.Tab
        RF_Panel                       matlab.ui.container.Panel
        PredictButton                  matlab.ui.control.Button
        ConfusionMatrixRFButton        matlab.ui.control.StateButton
        ConfusionMatrixRF              matlab.ui.container.Panel
        AccuracyEditField              matlab.ui.control.NumericEditField
        AccuracyEditFieldLabel         matlab.ui.control.Label
        RandomForestLabel              matlab.ui.control.Label
        RFPlot                         matlab.ui.control.UIAxes
        KMeansTab                      matlab.ui.container.Tab
        KM_Panel                       matlab.ui.container.Panel
        ConfusionMatrixKMButton        matlab.ui.control.StateButton
        PredictButton_3                matlab.ui.control.Button
        KMAccuracyEditField            matlab.ui.control.NumericEditField
        AccuracyEditField_2Label       matlab.ui.control.Label
        ConfusionMatrixKM              matlab.ui.container.Panel
        KMeansLabel                    matlab.ui.control.Label
        KMPlot                         matlab.ui.control.UIAxes
        SVMTab                         matlab.ui.container.Tab
        SVM_Panel                      matlab.ui.container.Panel
        ConfusionMatrixSVMButton       matlab.ui.control.StateButton
        PredictButton_2                matlab.ui.control.Button
        SVMAccuracyEditField           matlab.ui.control.NumericEditField
        AccuracyEditField_3Label       matlab.ui.control.Label
        ConfusionMatrixSVM             matlab.ui.container.Panel
        SVMLabel                       matlab.ui.control.Label
        SVMPlot                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        SVMModel %stores the SVM model 
        RFModel %stores the RF model 
        KMeansModel %stores the K means model 
        X_train
        y_train
        X_test
        y_test
        yresults
        RawData
        CleanData
        C % Description
        data
        idx % Description
        cmRF
        defaultVals
        test_row
    end
    
    methods (Access = private)
        
        function loadData(app)
    % Load the data
            raw_data = importdata('processed.cleveland.data');

            % Convert cell data to string, then split into numerical matrix
            if iscell(raw_data)
                raw_data = cellfun(@(x) str2double(strrep(x, '?', 'NaN')), raw_data, 'UniformOutput', false);
                app.data = cell2mat(raw_data);
            else
                app.data = raw_data;
            end
            % Remove rows with missing values (NaN)
            app.data(any(isnan(app.data), 2), :) = [];

            % Split features and labels
            X = app.data(:, 1:end-1);
            y = app.data(:, end);

            % Convert multi-class labels to binary (0: no disease, 1: disease)
            y(y > 0) = 1;
            
            app.yresults = y;

            % --- Train/Test Split (80% Training, 20% Testing) ---
            cv = cvpartition(size(X, 1), 'HoldOut', 0.2);
            app.X_train = X(training(cv,1), :);
            app.y_train = y(training(cv,1), :);
            app.X_test = X(test(cv,1), :);
            app.y_test = y(test(cv,1), :);

            loadedData = load("defaultValues.mat"); 
            app.defaultVals = loadedData.defaultVals;  

        end
        function loadModels(app)
    % Load SVM model
    tmp = load('mySVMModel.mat', 'SVMModel');
    app.SVMModel = tmp.SVMModel;

    % Load RF model
    tmp = load('myRFModel.mat', 'RFModel');
    app.RFModel = tmp.RFModel;

    % Load K-Means data (idx and c)
    tmp = load('myKMModel.mat', 'idx', 'C');
    app.idx = tmp.idx;
    app.C = tmp.C;
end

        
function initRFPage(app)

                            removeConfusionMatrixRF(app);

            y_pred_rf = str2double(predict(app.RFModel, app.X_test));


            % --- Evaluate Model Performance ---
            accuracy_rf = sum(y_pred_rf == app.y_test) / length(app.y_test);
            app.AccuracyEditField.Value = accuracy_rf*100;
            scatter3(app.RFPlot,app.data(y_pred_rf == 0, 1), app.data(y_pred_rf == 0, 8), app.data(y_pred_rf == 0, 5), 20, 'b', 'filled'); % No Disease
             hold(app.RFPlot,"on")
             scatter3(app.RFPlot,app.data(y_pred_rf == 1, 1), app.data(y_pred_rf == 1, 8), app.data(y_pred_rf == 1, 5), 20, 'r', 'filled'); % Disease
                         % % Labels and title
             xlabel(app.RFPlot,'Age(Years)');
             ylabel(app.RFPlot,'Max Heart Rate (bpm)');
             zlabel(app.RFPlot,'Cholesterol (mg/dL)');
             title(app.RFPlot,'3D Scatter Plot of Random Forest Trees');
            grid(app.RFPlot,"on");
             legend(app.RFPlot,'No Disease', 'Disease');
             hold(app.RFPlot,"off")
            app.cmRF = confusionchart(app.ConfusionMatrixRF,app.y_test, y_pred_rf);
            app.cmRF.Title = 'Confusion Matrix for Random Forest Classifier';


end
function initSVMPage(app)

                            removeConfusionMatrixSVM(app);

            
            y_pred_rf = predict(app.SVMModel, app.X_test);


            % --- Evaluate Model Performance ---
            accuracy_rf = sum(y_pred_rf == app.y_test) / length(app.y_test);
            app.SVMAccuracyEditField.Value = accuracy_rf*100;
            scatter3(app.SVMPlot,app.data(y_pred_rf == 0, 1), app.data(y_pred_rf == 0, 8), app.data(y_pred_rf == 0, 5), 20, 'b', 'filled'); % No Disease
             hold(app.SVMPlot,"on")
             scatter3(app.SVMPlot,app.data(y_pred_rf == 1, 1), app.data(y_pred_rf == 1, 8), app.data(y_pred_rf == 1, 5), 20, 'r', 'filled'); % Disease
                         % % Labels and title
             xlabel(app.SVMPlot,'Age(Years)');
             ylabel(app.SVMPlot,'Max Heart Rate (bpm)');
             zlabel(app.SVMPlot,'Cholesterol (mg/dL)');
             title(app.SVMPlot,'3D Scatter Plot of SVM');
            grid(app.SVMPlot,"on");
             legend(app.SVMPlot,'No Disease', 'Disease');
             hold(app.SVMPlot,"off")
            app.cmRF = confusionchart(app.ConfusionMatrixSVM,app.y_test, y_pred_rf);
            app.cmRF.Title = 'Confusion Matrix for SVM Classifier';
            app.SVMLabel.Visible = "on";


end
function initKMPage(app)

                            removeConfusionMatrixKM(app);

            
            y_pred_kmeans = app.idx - 1;

            % --- Evaluate Model Performance ---
            % accuracy_rf = sum(y_pred_rf == app.y_test) / length(app.y_test);

            % app.KMAccuracyEditField.Value = accuracy_rf*100;
            accuracy_kmeans = sum(y_pred_kmeans == app.yresults) / length(app.yresults);
            app.KMAccuracyEditField.Value = accuracy_kmeans*100;

            scatter3(app.KMPlot,app.data(y_pred_kmeans == 0, 1), app.data(y_pred_kmeans == 0, 8), app.data(y_pred_kmeans == 0, 5), 20, 'b', 'filled'); % No Disease
            hold(app.KMPlot,"on");
            scatter3(app.KMPlot,app.data(y_pred_kmeans == 1, 1), app.data(y_pred_kmeans == 1, 8), app.data(y_pred_kmeans == 1, 5), 20, 'r', 'filled'); % Disease


            xlabel(app.KMPlot,'Age(Years)');
            ylabel(app.KMPlot,'Max Heart Rate (bpm)');
            zlabel(app.KMPlot,'Cholesterol (mg/dL)');
             title(app.KMPlot,'3D Scatter Plot of K-Means Clusters');
            grid(app.KMPlot,"on");
            legend(app.KMPlot,'No Disease', 'Disease');
            hold(app.KMPlot,"off")
             
            app.cmRF = confusionchart(app.ConfusionMatrixKM,app.yresults, y_pred_kmeans);
            app.cmRF.Title = 'Confusion Matrix for K-Means Classifier';
            app.KMeansLabel.Visible = "on";

            % Evaluate K-Means Classification Performance


end
function displayConfusionMatrixRF(app)
    app.ConfusionMatrixRF.Visible = "on";
    app.RFPlot.Position = [10 91 381 259];
    app.RFPlot.YDir = 'normal';
end


function removeConfusionMatrixRF(app)
    app.ConfusionMatrixRF.Visible = "off"
    app.RFPlot.Position =[10 87 616 259];
    
end

function displayConfusionMatrixKM(app)
    app.ConfusionMatrixKM.Visible = "on"
    app.KMPlot.Position = [10 91 381 259];

end

function removeConfusionMatrixKM(app)
    app.ConfusionMatrixKM.Visible = "off"
    app.KMPlot.Position =[10 87 616 259];
end

function displayConfusionMatrixSVM(app)
    app.ConfusionMatrixSVM.Visible = "on"
    app.SVMPlot.Position = [10 91 381 259];

end

function removeConfusionMatrixSVM(app)
    app.ConfusionMatrixSVM.Visible = "off"
    app.SVMPlot.Position =[10 87 616 259];
end

function displayModal(app,event,model)
    app.PredictHeartDiseaseModal.Visible = "on";
    app.LabelModel.Text = model;

end

function removeModal(app,event,model)
    app.PredictHeartDiseaseModal.Visible = "off";
end
    
function RunRFPage(app)


            % 
            % %plotting the pred against age chol and thalach.

            % 

            % Displays Page
            app.RFPlot.Visible ="on";
            app.AccuracyEditField.Visible = "on";
            app.AccuracyEditFieldLabel.Visible = "on";
            % 
            % 
            %create RF
                        removeConfusionMatrixRF(app);

            % % Confusion Matrix for RF


    end
    

function vital_row = createTestData(app,Age, Thalach, Cholestrol)
        vital_row = [ floor(app.defaultVals.Age),      floor(app.defaultVals.Sex),      floor(app.defaultVals.Cp), ...
                      floor(app.defaultVals.Trestbps), floor(app.defaultVals.Chol),     floor(app.defaultVals.Fbs), ...
                      floor(app.defaultVals.Restecg),  floor(app.defaultVals.Thalach),  floor(app.defaultVals.Exang), ...
                      floor(app.defaultVals.Oldpeak),  floor(app.defaultVals.Slope),    floor(app.defaultVals.Ca), ...
                      floor(app.defaultVals.Thal) ];

        vital_row = [ app.defaultVals.Age,      app.defaultVals.Sex,      app.defaultVals.Cp, ...
              app.defaultVals.Trestbps, app.defaultVals.Chol,     app.defaultVals.Fbs, ...
              app.defaultVals.Restecg,  app.defaultVals.Thalach,  app.defaultVals.Exang, ...
              app.defaultVals.Oldpeak,  app.defaultVals.Slope,    app.defaultVals.Ca, ...
              app.defaultVals.Thal ];

        % Override with user-supplied values
disp("Default");
disp(vital_row)
% vital_row = [58.0, 1.0, 3.0, 132.0, 224.0, 0. 0, 2.0, 173.0, 0.0, 3.2, 1.0, 2.0, 7.0];   
            data = [59, 1, 4, 132, 190, 0, 0, 120, 0, 1, 2, 0, 3];
        vital_row(1) = floor(Age);         % Age
        vital_row(5) = floor(Cholestrol);  % Chol
        vital_row(8) = floor(Thalach);     % Thalach

    
end
    function fillScale(app,event)

      test_subject = createTestData(app,app.AgeKnob.Value, app.ThalachKnob.Value, app.CholestrolKnob.Value);
        
      disp([floor(app.AgeKnob.Value),floor(app.ThalachKnob.Value),floor(app.CholestrolKnob.Value)])

      disp(test_subject);
      % y_pred = predict(app.RFModel, test_subject);
      % disp(y_pred)

     switch app.LabelModel.Text
     
     case "SVM"
         y_pred = predict(app.SVMModel, test_subject);
     case "K-Means"
         y_pred = str2double(predict(app.RFModel, test_subject));
     case "Random Forest"
         y_pred = str2double(predict(app.RFModel, test_subject));
         otherwise   "Random Forest"
         y_pred = str2double(predict(app.RFModel, test_subject));

    
    end


    disp(y_pred)

        app.HeartDiseaseGauge.Value =y_pred;

      
    end


    function RunSVMPage(app)
    % 1) Predict using your SVM model
            app.SVMPlot.Visible ="on";
            app.SVMAccuracyEditField.Visible = "on";
            app.SVMLabel.Visible = "on";
            % 
            % 
            %create RF
        removeConfusionMatrixSVM(app);

    end
    function RunKMPage(app)
    %--------------------------------------------------
            app.KMPlot.Visible ="on";
            app.KMAccuracyEditField.Visible = "on";
            app.KMeansLabel.Visible = "on";
            % 
            % 
            %create RF
        removeConfusionMatrixSVM(app);
end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            loadData(app);
            loadModels(app);
            initRFPage(app);
            initSVMPage(app);
            initKMPage(app);
        end

        % Callback function
        function KMeansClusteringButtonPushed(app, event)
            km
            close(app.UIFigure)
        end

        % Callback function
        function RandomForestRFButtonPushed(app, event)
            rf
            close(app.UIFigure)
        end

        % Callback function
        function SupportVectorMachineSVMButtonPushed(app, event)
            svm
            close(app.UIFigure)
        end

        % Callback function
        function LoadDataButtonPushed(app, event)
            data = readtable('processed.cleveland.data','FileType','text','ReadRowNames',false);
        end

        % Callback function
        function testdataplotButtonPushed(app, event)
            %% just a test
            data = readtable('processed.cleveland.data','FileType','text','ReadRowNames',false);
            plot(app.RFPlot,data{:,1},data{:,2})
            title(app.RFPlot,"Proof of concept")
        end

        % Callback function: not associated with a component
        function ConfusionMatrixButton_2Pushed(app, event)
           app.KMPlot.Position = [10,173,329,201];
           app.KMPlot.YDir = 'normal';
           app.ConfusionMatrixKM.Visible = "On";
        end

        % Callback function: not associated with a component
        function ConfusionMatrixButton_3Pushed(app, event)
           app.SVMPlot.Position = [10,173,329,201];
           app.SVMPlot.YDirPus = 'normal';
           app.ConfusionMatrixSVM.Visible = "On";

        end

        % Selection change function: TabGroup
        function tabChanged(app, event)
        selectedTab = event.NewValue;
        
        % Check which tab is selected
        if selectedTab == app.RandomForestTab
            RunRFPage(app);
        elseif selectedTab == app.KMeansTab
            RunKMPage(app);
        elseif selectedTab == app.SVMTab
            RunSVMPage(app);
        end
        end

        % Callback function: not associated with a component
        function ConfusionMatrixSwitchValueChanged(app, event)
            value = app.ConfusionMatrixSwitch.Value;
            if value == true
                displayConfusionMatrixRF(app);
            else 
                removeConfusionMatrixRF(app);
            end
        end

        % Value changed function: ConfusionMatrixRFButton
        function ConfusionMatrixRFButtonValueChanged(app, event)
            value = app.ConfusionMatrixRFButton.Value;
            if value
                displayConfusionMatrixRF(app);
            else 
                removeConfusionMatrixRF(app);
            end
        end

        % Button down function: PredictHeartDiseaseModal
        function modalBounds(app, event)
    % Get the current point of the click in the figure window (in pixels)
    clickPoint = get(gcf, 'CurrentPoint');
    
    % Get the panel's position in pixel units [left bottom width height]
    panelPos = getpixelposition(hPanel, true);
    
    % Extract the click coordinates for clarity
    xClick = clickPoint(1);
    yClick = clickPoint(2);
    
    % Check if the click is outside the panel's bounds
    if xClick < panelPos(1) || xClick > (panelPos(1) + panelPos(3)) || ...
       yClick < panelPos(2) || yClick > (panelPos(2) + panelPos(4))
        % If the click is out of bounds, hide the panel
        set(hPanel, 'Visible', 'off');
    end

        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            removeModal(app);
        end

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            displayModal(app,event,"Random Forest");
        end

        % Callback function: AgeKnob, AgeKnob, CholestrolKnob, 
        % ...and 3 other components
        function AgeEditFieldValueChanged(app, event)
            fillScale(app,event);            
        end

        % Callback function: not associated with a component
        function CholestrolKnobValueChanged(app, event)
            value = app.CholestrolKnob.Value;
            
        end

        % Value changed function: ConfusionMatrixKMButton
        function ConfusionMatrixKMButtonValueChanged(app, event)
            value = app.ConfusionMatrixKMButton.Value;
            if value
                displayConfusionMatrixKM(app);
            else 
                removeConfusionMatrixKM(app);
            end
        end

        % Button pushed function: PredictButton_3
        function PredictButton_3Pushed(app, event)
                        displayModal(app,event,"K-Means");

        end

        % Button pushed function: PredictButton_2
        function PredictButton_2Pushed(app, event)
            displayModal(app,event,"SVM");

        end

        % Value changed function: ConfusionMatrixSVMButton
        function ConfusionMatrixSVMButtonValueChanged(app, event)
            value = app.ConfusionMatrixSVMButton.Value;
            if value
                displayConfusionMatrixSVM(app);
            else 
                removeConfusionMatrixSVM(app);
            end
        end

        % Button pushed function: KMeansClusteringButton
        function KMeansClusteringButtonPushed2(app, event)
            app.TabGroup.SelectedTab = app.KMeansTab;
        end

        % Button pushed function: RandomForestRFButton
        function RandomForestRFButtonPushed2(app, event)
                        app.TabGroup.SelectedTab = app.RandomForestTab;
        end

        % Button pushed function: SupportVectorMachineSVMButton
        function SupportVectorMachineSVMButtonPushed2(app, event)
                        app.TabGroup.SelectedTab = app.SVMTab;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @tabChanged, true);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.Panel = uipanel(app.HomeTab);
            app.HeartDiseaseLabel = uilabel(app.Panel);
            app.HeartDiseaseLabel.Text = 'Heart Disease';
            app.KMeansClusteringButton = uibutton(app.Panel, 'push');
            app.KMeansClusteringButton.ButtonPushedFcn = createCallbackFcn(app, @KMeansClusteringButtonPushed2, true);
            app.KMeansClusteringButton.Text = 'K-Means Clustering';
            app.RandomForestRFButton = uibutton(app.Panel, 'push');
            app.RandomForestRFButton.ButtonPushedFcn = createCallbackFcn(app, @RandomForestRFButtonPushed2, true);
            app.RandomForestRFButton.Text = 'Random Forest(RF)';
            app.SupportVectorMachineSVMButton = uibutton(app.Panel, 'push');
            app.SupportVectorMachineSVMButton.ButtonPushedFcn = createCallbackFcn(app, @SupportVectorMachineSVMButtonPushed2, true);
            app.SupportVectorMachineSVMButton.Text = 'Support Vector Machine(SVM)';
            app.ConclusionsandInferencesTextAreaLabel = uilabel(app.Panel);
            app.ConclusionsandInferencesTextAreaLabel.Text = 'Conclusions and Inferences';
            app.ConclusionsandInferencesTextArea = uitextarea(app.Panel);
            app.ConclusionsandInferencesTextArea.Editable = 'off';
            app.ConclusionsandInferencesTextArea.Value = {'Out of the 3 models, Random Forest consistenly scores the highest in accuracy, followed by SVM then K-Means.'; ''; ''};
            app.CitationsTextAreaLabel = uilabel(app.Panel);
            app.CitationsTextAreaLabel.Text = 'Citations';
            app.CitationsTextArea = uitextarea(app.Panel);
            app.CitationsTextArea.Editable = 'off';
            app.CitationsTextArea.Value = {'UCI Machine Learning Repository. "Cleveland Heart Disease Data Set." UCI Repository, https://archive.ics.uci.edu/ml/datasets/Heart+Disease.'; 'Detrano, R., et al. "Cleveland Heart Disease Data Set." UCI Machine Learning Repository, 1988. '; ''};
            app.RandomForestTab = uitab(app.TabGroup);
            app.RandomForestTab.Title = 'Random Forest';
            app.RF_Panel = uipanel(app.RandomForestTab);
            app.RFPlot = uiaxes(app.RF_Panel);
            title(app.RFPlot, 'Title')
            xlabel(app.RFPlot, 'X')
            ylabel(app.RFPlot, 'Y')
            zlabel(app.RFPlot, 'Z')
            app.RFPlot.Visible = 'off';
            app.RandomForestLabel = uilabel(app.RF_Panel);
            app.RandomForestLabel.Text = 'Random Forest';
            app.AccuracyEditFieldLabel = uilabel(app.RF_Panel);
            app.AccuracyEditFieldLabel.Visible = 'off';
            app.AccuracyEditFieldLabel.Text = 'Accuracy';
            app.AccuracyEditField = uieditfield(app.RF_Panel, 'numeric');
            app.AccuracyEditField.Editable = 'off';
            app.AccuracyEditField.Visible = 'off';
            app.ConfusionMatrixRF = uipanel(app.RF_Panel);
            app.ConfusionMatrixRFButton = uibutton(app.RF_Panel, 'state');
            app.ConfusionMatrixRFButton.ValueChangedFcn = createCallbackFcn(app, @ConfusionMatrixRFButtonValueChanged, true);
            app.ConfusionMatrixRFButton.Text = 'Confusion Matrix';
            app.PredictButton = uibutton(app.RF_Panel, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = 'Predict!';
            app.KMeansTab = uitab(app.TabGroup);
            app.KMeansTab.Title = 'K-Means';
            app.KM_Panel = uipanel(app.KMeansTab);
            app.KMPlot = uiaxes(app.KM_Panel);
            title(app.KMPlot, 'Title')
            xlabel(app.KMPlot, 'X')
            ylabel(app.KMPlot, 'Y')
            zlabel(app.KMPlot, 'Z')
            app.KMPlot.Visible = 'off';
            app.KMeansLabel = uilabel(app.KM_Panel);
            app.KMeansLabel.Text = 'K-Means';
            app.ConfusionMatrixKM = uipanel(app.KM_Panel);
            app.AccuracyEditField_2Label = uilabel(app.KM_Panel);
            app.AccuracyEditField_2Label.Visible = 'off';
            app.AccuracyEditField_2Label.Text = 'Accuracy';
            app.KMAccuracyEditField = uieditfield(app.KM_Panel, 'numeric');
            app.KMAccuracyEditField.Editable = 'off';
            app.KMAccuracyEditField.Visible = 'off';
            app.PredictButton_3 = uibutton(app.KM_Panel, 'push');
            app.PredictButton_3.ButtonPushedFcn = createCallbackFcn(app, @PredictButton_3Pushed, true);
            app.PredictButton_3.Text = 'Predict!';
            app.ConfusionMatrixKMButton = uibutton(app.KM_Panel, 'state');
            app.ConfusionMatrixKMButton.ValueChangedFcn = createCallbackFcn(app, @ConfusionMatrixKMButtonValueChanged, true);
            app.ConfusionMatrixKMButton.Text = 'Confusion Matrix';
            app.SVMTab = uitab(app.TabGroup);
            app.SVMTab.Title = 'SVM';
            app.SVM_Panel = uipanel(app.SVMTab);
            app.SVMPlot = uiaxes(app.SVM_Panel);
            title(app.SVMPlot, 'Title')
            xlabel(app.SVMPlot, 'X')
            ylabel(app.SVMPlot, 'Y')
            zlabel(app.SVMPlot, 'Z')
            app.SVMPlot.Visible = 'off';
            app.SVMLabel = uilabel(app.SVM_Panel);
            app.SVMLabel.Text = 'SVM';
            app.ConfusionMatrixSVM = uipanel(app.SVM_Panel);
            app.AccuracyEditField_3Label = uilabel(app.SVM_Panel);
            app.AccuracyEditField_3Label.Visible = 'off';
            app.AccuracyEditField_3Label.Text = 'Accuracy';
            app.SVMAccuracyEditField = uieditfield(app.SVM_Panel, 'numeric');
            app.SVMAccuracyEditField.Editable = 'off';
            app.SVMAccuracyEditField.Visible = 'off';
            app.PredictButton_2 = uibutton(app.SVM_Panel, 'push');
            app.PredictButton_2.ButtonPushedFcn = createCallbackFcn(app, @PredictButton_2Pushed, true);
            app.PredictButton_2.Text = 'Predict!';
            app.ConfusionMatrixSVMButton = uibutton(app.SVM_Panel, 'state');
            app.ConfusionMatrixSVMButton.ValueChangedFcn = createCallbackFcn(app, @ConfusionMatrixSVMButtonValueChanged, true);
            app.ConfusionMatrixSVMButton.Text = 'Confusion Matrix';
            app.PredictHeartDiseaseModal = uipanel(app.UIFigure);
            app.PredictHeartDiseaseModal.Title = 'Predict Heart Disease';
            app.PredictHeartDiseaseModal.Visible = 'off';
            app.HeartDiseaseGaugeLabel = uilabel(app.PredictHeartDiseaseModal);
            app.HeartDiseaseGaugeLabel.Text = 'Heart Disease';
            app.HeartDiseaseGauge = uigauge(app.PredictHeartDiseaseModal, 'linear');
            app.HeartDiseaseGauge.Limits = [0 1];
            app.LabelModel = uilabel(app.PredictHeartDiseaseModal);
            app.LabelModel.Text = 'SVM';
            app.CloseButton = uibutton(app.PredictHeartDiseaseModal, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.ThalachKnobLabel = uilabel(app.PredictHeartDiseaseModal);
            app.ThalachKnobLabel.Text = 'Thalach';
            app.ThalachKnob = uiknob(app.PredictHeartDiseaseModal, 'continuous');
            app.ThalachKnob.Limits = [100 230];
            app.ThalachKnob.ValueChangedFcn = createCallbackFcn(app, @AgeEditFieldValueChanged, true);
            app.ThalachKnob.ValueChangingFcn = createCallbackFcn(app, @AgeEditFieldValueChanged, true);
            app.ThalachKnob.Value = 170;
            app.AgeLabel = uilabel(app.PredictHeartDiseaseModal);
            app.AgeLabel.Text = 'Age';
            app.AgeKnob = uiknob(app.PredictHeartDiseaseModal, 'continuous');
            app.AgeKnob.Limits = [50 70];
            app.AgeKnob.ValueChangedFcn = createCallbackFcn(app, @AgeEditFieldValueChanged, true);
            app.AgeKnob.ValueChangingFcn = createCallbackFcn(app, @AgeEditFieldValueChanged, true);
            app.AgeKnob.Value = 50;
            app.CholestrolKnobLabel = uilabel(app.PredictHeartDiseaseModal);
            app.CholestrolKnobLabel.Text = 'Cholestrol';
            app.CholestrolKnob = uiknob(app.PredictHeartDiseaseModal, 'continuous');
            app.CholestrolKnob.Limits = [150 250];
            app.CholestrolKnob.ValueChangedFcn = createCallbackFcn(app, @AgeEditFieldValueChanged, true);
            app.CholestrolKnob.ValueChangingFcn = createCallbackFcn(app, @AgeEditFieldValueChanged, true);
            app.CholestrolKnob.Value = 150;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = home_app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end