classdef svm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Panel                   matlab.ui.container.Panel
        AccuracyEditField       matlab.ui.control.NumericEditField
        AccuracyEditFieldLabel  matlab.ui.control.Label
        Panel_2                 matlab.ui.container.Panel
        ConfusionMatrixButton   matlab.ui.control.Button
        RunSVMButton            matlab.ui.control.Button
        HomeButton              matlab.ui.control.Button
        SVMLabel                matlab.ui.control.Label
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            home_app
            close(app.UIFigure)
        end

        % Button pushed function: RunSVMButton
        function RunSVMButtonPushed(app, event)
            % Load data using importdata
            raw_data = importdata('processed.cleveland.data');

            % Convert cell data to string, then split into numerical matrix
            if iscell(raw_data)
                raw_data = cellfun(@(x) str2double(strrep(x, '?', 'NaN')), raw_data, 'UniformOutput', false);
                data = cell2mat(raw_data);
            else
                data = raw_data;
            end

            % Remove rows with missing values (NaN)
            data(any(isnan(data), 2), :) = [];

            % Split features and labels
            X = data(:, 1:end-1);
            y = data(:, end);

            % Convert multi-class labels to binary (0: no disease, 1: disease)
            y(y > 0) = 1;


            % Split data into training (80%) and testing (20%)
            cv = cvpartition(size(X, 1), 'HoldOut', 0.2);
            X_train = X(training(cv,1), :);
            y_train = y(training(cv,1), :);
            X_test = X(test(cv,1), :);
            y_test = y(test(cv,1), :);

            % Train SVM model
            SVMModel = fitcsvm(X_train, y_train, 'KernelFunction', 'linear', 'Standardize', true);

            % Make predictions
            y_pred = predict(SVMModel, X_test);

            % --- Evaluate Model Performance ---
            accuracy_svm = sum(y_pred == y_test) / length(y_test);
            app.AccuracyEditField.Value = accuracy_svm*100;

            %plotting the pred against age chol and thalach.
            scatter3(app.UIAxes,data(y_pred == 0, 1), data(y_pred == 0, 8), data(y_pred == 0, 5), 20, 'b', 'filled'); % No Disease
            hold(app.UIAxes,"on")
            scatter3(app.UIAxes,data(y_pred == 1, 1), data(y_pred == 1, 8), data(y_pred == 1, 5), 20, 'r', 'filled'); % Disease

            % Labels and title
            xlabel(app.UIAxes,'Age(Years)');
            ylabel(app.UIAxes,'Max Heart Rate (bpm)');
            zlabel(app.UIAxes,'Cholesterol (mg/dL)');
            title(app.UIAxes,'3D Scatter Plot of K-means Clusters');
            grid(app.UIAxes,"on");
            legend(app.UIAxes,'No Disease', 'Disease');
            hold(app.UIAxes,"off")

            % Housekeeping
            app.UIAxes.Visible ="on";
            app.RunSVMButton.Position = [271,105,100,23];
            app.AccuracyEditField.Visible = "on";
            app.AccuracyEditFieldLabel.Visible = "on";
            app.ConfusionMatrixButton.Visible = "on";
            
            % Confusion Matrix for SVM
            cm = confusionchart(app.Panel_2,y_test, y_pred);
            cm.Title = 'Confusion Matrix for SVM Model';
            cm.Visible = "on";


        end

        % Button pushed function: ConfusionMatrixButton
        function ConfusionMatrixButtonPushed(app, event)
           app.UIAxes.Position = [10,173,329,201];
           app.Panel_2.Visible = "On";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.SVMLabel = uilabel(app.Panel);
            app.SVMLabel.Text = 'SVM';
            app.HomeButton = uibutton(app.Panel, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.RunSVMButton = uibutton(app.Panel, 'push');
            app.RunSVMButton.ButtonPushedFcn = createCallbackFcn(app, @RunSVMButtonPushed, true);
            app.RunSVMButton.Text = 'Run SVM';
            app.ConfusionMatrixButton = uibutton(app.Panel, 'push');
            app.ConfusionMatrixButton.ButtonPushedFcn = createCallbackFcn(app, @ConfusionMatrixButtonPushed, true);
            app.ConfusionMatrixButton.Visible = 'off';
            app.ConfusionMatrixButton.Text = 'Confusion Matrix';
            app.Panel_2 = uipanel(app.Panel);
            app.Panel_2.Visible = 'off';
            app.AccuracyEditFieldLabel = uilabel(app.Panel);
            app.AccuracyEditFieldLabel.Visible = 'off';
            app.AccuracyEditFieldLabel.Text = 'Accuracy';
            app.AccuracyEditField = uieditfield(app.Panel, 'numeric');
            app.AccuracyEditField.Editable = 'off';
            app.AccuracyEditField.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = svm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end